classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        slider_1_disp              matlab.ui.control.Label
        FilterDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel        matlab.ui.control.Label
        LowpassSlider              matlab.ui.control.Slider
        LowpassSliderLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        stop_Button                matlab.ui.control.Button
        play_Button                matlab.ui.control.Button
        samplerateEditField        matlab.ui.control.NumericEditField
        samplerateEditFieldLabel   matlab.ui.control.Label
        browseButton               matlab.ui.control.Button
        fileaddressEditField       matlab.ui.control.EditField
        fileaddressEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        filename;
        pathname;
        fs=0;
        audio;
        gainsArr = ones(1, 9);
    end
    
    methods (Access = private)
        
        
        function apply(app)
            app.enhancedAudio = apply_filters(app.originalAudio, app.originalSampleRate, app.gains, app.filterClass);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            [file, Pathname]=uigetfile('*.wav','wav file selector');
            app.filename = file;
            app.pathname=strcat(file, Pathname);
            [app.audio, app.fs]=audioread(file);
            app.fileaddressEditField.Value=app.filename;
            app.samplerateEditField.Value=app.fs;
        end

        % Button pushed function: play_Button
        function play_ButtonPushed(app, event)
            clear sound
            sound(app.audio, app.fs);
        end

        % Button pushed function: stop_Button
        function stop_ButtonPushed(app, event)
            clear sound;
        end

        % Value changing function: LowpassSlider
        function LowpassSliderValueChanging(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(5) = value;
            app.slider_1_disp.Text = mat2str(value, 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.fileaddressEditFieldLabel = uilabel(app.Panel);
            app.fileaddressEditFieldLabel.Text = 'file address';
            app.fileaddressEditField = uieditfield(app.Panel, 'text');
            app.fileaddressEditField.Placeholder = 'browse file';
            app.browseButton = uibutton(app.Panel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.samplerateEditFieldLabel = uilabel(app.Panel);
            app.samplerateEditFieldLabel.Text = 'sample rate';
            app.samplerateEditField = uieditfield(app.Panel, 'numeric');
            app.play_Button = uibutton(app.Panel, 'push');
            app.play_Button.ButtonPushedFcn = createCallbackFcn(app, @play_ButtonPushed, true);
            app.play_Button.Icon = 'OIP.jpeg';
            app.play_Button.Text = {'Play'; ''};
            app.stop_Button = uibutton(app.Panel, 'push');
            app.stop_Button.ButtonPushedFcn = createCallbackFcn(app, @stop_ButtonPushed, true);
            app.stop_Button.Icon = 'OIP (1).jpeg';
            app.stop_Button.Text = 'Stop';
            app.LowpassSliderLabel = uilabel(app.UIFigure);
            app.LowpassSliderLabel.Text = 'Low pass';
            app.LowpassSlider = uislider(app.UIFigure);
            app.LowpassSlider.Limits = [-24 24];
            app.LowpassSlider.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.LowpassSlider.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.LowpassSlider.ValueChangingFcn = createCallbackFcn(app, @LowpassSliderValueChanging, true);
            app.LowpassSlider.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'FIR', 'IIR'};
            app.FilterDropDown.Placeholder = '-';
            app.FilterDropDown.Value = 'FIR';
            app.slider_1_disp = uilabel(app.UIFigure);
            app.slider_1_disp.Text = '0db';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end